classdef GUInotgooey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [status,result] = system('arp -a'); %windows command to get a bunch of ip addresses
            pat = regexpPattern("192\.168\.137\.[0-9]+");
            ipAddrArray = extract(result, pat); %use the regex to find 0 or 1 ip addresses
            
            [r, c]=size(ipAddrArray);
            
            %if there was an ip address found
            if r==1
                ipAddr = ipAddrArray{1}
                
                %connect as a client (robot is the server)
                client = tcpclient(ipAddr, 23, "Timeout", 5); %to check ip address: right click mobile hotspot, go to settings
                fprintf("got client\n");
                
                %send a message
                fprintf("sending a message\n");
                client.write("Super grate message to send to the robot becasue sending messages is cool")
                client.flush()
                fprintf("done sending a message\n");
                
                while(true)
                    %receive a message
                    received = client.readline();
                    fprintf("Message received: '");
                    fprintf(received)
                    fprintf("'\nDone receiving message\n");
                end
            
                %disconnect from robot
                delete(client)
            
            else %no ip address found
                fprintf("No ip found, check if hotspot is on and robot is on\n");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUInotgooey

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end